classdef SigViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CH8Lamp                 matlab.ui.control.Lamp
        CH1KLamp_8Label         matlab.ui.control.Label
        CH7Lamp                 matlab.ui.control.Lamp
        CH1KLamp_7Label         matlab.ui.control.Label
        CH6Lamp                 matlab.ui.control.Lamp
        CH1KLamp_6Label         matlab.ui.control.Label
        CH5Lamp                 matlab.ui.control.Lamp
        CH5KLabel               matlab.ui.control.Label
        CH4Lamp                 matlab.ui.control.Lamp
        CH1KLamp_4Label         matlab.ui.control.Label
        CH3Lamp                 matlab.ui.control.Lamp
        CH3KLabel               matlab.ui.control.Label
        CH2Lamp                 matlab.ui.control.Lamp
        CH2KLabel               matlab.ui.control.Label
        CH1Lamp                 matlab.ui.control.Lamp
        CH1KLabel               matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        RefrenceDropDown        matlab.ui.control.DropDown
        RefrenceDropDownLabel   matlab.ui.control.Label
        NotchCheckBox           matlab.ui.control.CheckBox
        HighPassDropDown        matlab.ui.control.DropDown
        HighPassDropDownLabel   matlab.ui.control.Label
        LowPassDropDown         matlab.ui.control.DropDown
        LowPassDropDownLabel    matlab.ui.control.Label
        AmplitudeDropDown       matlab.ui.control.DropDown
        AmplitudeDropDownLabel  matlab.ui.control.Label
        ImpedanceButton         matlab.ui.control.StateButton
        ConnectButton           matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialDevice                   % 串口设备
        EMG_CHANNEL
        emg_cnt_max
        EMG_bytes           % 记录一帧的字节数据
        emg_cnt_state       % 记录switch函数的状态
        emg_cnt_count       % 每次计数到emg_cnt_max为止
        emg_idx
        EMG_frame           % 数据解析后的数据帧
        result_emg_idx
        XL
        line_EMG
        Ifrun               % while是否运行

        Offset              % 通道间幅值偏移量
        offsetA               % 用来构造偏移量的矩阵
        offsetB               % 用来构造偏移量的矩阵

        %%HighPass
        fs = 500;%采样频率
        filt_n=2;%2阶滤波
        fc_high = [0.1 1 5];%截止频率
        b_high
        a_high
        zi_high=[];
        en_highpass=1;
        %%LowPass
        fc_low = [30 40 70];   
        b_low
        a_low
        zi_low=[];
        en_lowpass=1;
        %%notch
        fc_notch = [49 51];  
        b_notch
        a_notch
        zi_notch=[];
        en_notch=1;

        %%计算阻抗
        impe_RawData
        impe_Raw_index

    end
    
    properties (Access = public)
        result_emg          % 绘图更新缓冲区，refreshdata处要求public属性
    end
    
    methods (Access = private)
        %%数据接收并解码
        function EEGSig(app)
            while app.Ifrun
                buff=read(app.SerialDevice,660,'uint8');
                for index=1:length(buff)
                    switch(app.emg_cnt_state)
                        case 0
                            if(buff(index)==187) %0xBB
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 1
                            if(buff(index)==170) %0xAA
                                app.emg_cnt_state=2;
                                app.emg_cnt_count=1;
                                emg_sumchkm = 0;
                            elseif(buff(index)==187)%排除0xBB BB AA的情况
                                app.emg_cnt_state=1;
                            else
                                app.emg_cnt_state=0;
                            end
                        case 2
                            app.EMG_bytes(1,app.emg_cnt_count) = buff(index);
                            app.emg_cnt_count = app.emg_cnt_count + 1;
                            emg_sumchkm = emg_sumchkm + buff(index);
                            if(app.emg_cnt_count == app.emg_cnt_max+1)%%判断数据接收是否结束
                                app.emg_cnt_state=3;
                            else
                                app.emg_cnt_state=2;
                            end
                        case 3
                            if(buff(index) == 0)%%检查校验位
                                app.emg_cnt_state=4;
                            else
                                app.emg_cnt_state=0;
                                disp(app.emg_idx)%%校验错误,打印当前帧位置和数据
                            end
                        case 4
                            EMG_Sequence(app.emg_idx,1) = buff(index);%%%记录包序号

                            % 原始数据解析%

                            for i=1:9
                                for j=1:8
                                    if(app.EMG_bytes(1,j*3-2+24*(i-1)) > 127)%%%第8位是否为1
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([255 app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    else
                                        app.EMG_frame(i,j) = swapbytes(typecast(uint8([0   app.EMG_bytes((j*3-2+24*(i-1)):j*3+24*(i-1))]),'int32'));
                                    end
                                end
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            %%%%%%%%%%%%在这里添加存储数据的代码%%%%%%%%%%%
%                             Sigfilter(app);
                            app.impe_RawData(app.impe_Raw_index*9+1:(app.impe_Raw_index+1)*9,:) = app.EMG_frame;
                            app.impe_Raw_index = app.impe_Raw_index +1;
                            if app.impe_Raw_index == 114
                                app.impe_Raw_index = 0;
                                ImpeValue = fft(app.impe_RawData,1024);
                                tt=max(abs(ImpeValue(62:67,:)));
                                q=tt*1000/(500/2)*0.02235174/6-5000;
                                app.CH1KLabel.Text = strcat('CH1:',string(fix(q(1))),'KΩ');
                                app.CH1Lamp.Color = 'r';
                            end
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            app.emg_idx = app.emg_idx + 1;
                            app.emg_cnt_state=0;  % switch状态切换%
                            % 绘图缓存填充%
                            app.result_emg((app.result_emg_idx-1)*9+1:app.result_emg_idx*9,:)=app.EMG_frame*0.02235174 + app.offsetB*app.Offset;
                            app.result_emg_idx=mod(app.result_emg_idx,278)+1;

                    end
                end
                %%刷新图像
                refreshdata(app.line_EMG,'caller');
                app.XL.Value = (app.result_emg_idx-1)*9;
                drawnow limitrate 
            end

        end
        
        
        function Sigfilter(app)
            if app.en_highpass ==1
                [app.EMG_frame,app.zi_high] = filter(app.b_high,app.a_high,app.EMG_frame,app.zi_high,2);
            end
            if app.en_lowpass == 1
                [app.EMG_frame,app.zi_low] = filter(app.b_low,app.a_low,app.EMG_frame,app.zi_low,2);
            end
            if app.en_notch == 1
                [app.EMG_frame,app.zi_notch] = filter(app.b_notch,app.a_notch,app.EMG_frame,app.zi_notch,2);
            end            
        end
        
        function Impecalculate(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% APP初始化
            app.EMG_CHANNEL=8;
            app.emg_cnt_max=app.EMG_CHANNEL*3*9;
            app.EMG_bytes=zeros(1,app.emg_cnt_max);%%%记录一帧的字节数据
            app.emg_cnt_state=0;%%%记录switch函数的状态
            app.emg_cnt_count=0;%%%每次计数到emg_cnt_max为止
            app.emg_idx=1;
            app.EMG_frame=zeros(9,app.EMG_CHANNEL);%%%数据解析后的数据帧
            app.result_emg=zeros(2502,app.EMG_CHANNEL) - 1;%%%绘图更新缓冲区，减1为了初始时不显示y=0的横线
            app.result_emg_idx=1;
            
            app.Offset=200;
            app.offsetA=[0.5 1.5 2.5 3.5 4.5 5.5 6.5 7.5];
            app.offsetB=zeros(9,8);
            app.offsetB = app.offsetB + app.offsetA;

            [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(3)/(app.fs/2),'high');
            [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(1)/(app.fs/2),'low');
            [app.b_notch,app.a_notch] = butter(2,app.fc_notch/(app.fs/2),'stop');

            app.impe_RawData=zeros(1026,8);
            app.impe_Raw_index = 0;


            %设置图的数据源
            T=['app.result_emg(:,  )'];
            for Z=1:8
                app.line_EMG{Z}=plot(app.UIAxes,app.result_emg(:,Z));
                if Z<10
                    T(end-1)=string(Z);
                else
                    T(end-2:end-1)=string(Z);
                end
                set(app.line_EMG{Z},'YDataSource',T);  % 设置y轴数据来源 data1
                hold(app.UIAxes,"on")      % 为了让多条图线在一个图中显示
            end

            %设置垂直线
            app.XL = xline(app.UIAxes,0);
            
            app.Ifrun = false;
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            %% 串口连接按钮按下事件
            value = app.ConnectButton.Value;
            if value == 1
                delete(instrfindall);
                scom = 'COM4'; %name of serial
                Baudrate = 921600; %serial baudrate, should be same as the mcu setting
                try
                    app.SerialDevice = serialport(scom,Baudrate);
                    app.ConnectButton.Text='Disconnect';
                    app.ConnectButton.BackgroundColor=[0.07 0.62 1.00];
                    disp('串口打开成功');
                catch
                    disp('串口打开失败');
                    delete(app.SerialDevice);
                end
            else
                delete(app.SerialDevice);
                app.ConnectButton.Text = 'Connect';
                app.ConnectButton.BackgroundColor = [0.8 0.8 0.8];
            end
        end

        % Value changed function: ImpedanceButton
        function ImpedanceButtonValueChanged(app, event)
            %% 运行模式EEG/IMPE按钮按下事件
            value = app.ImpedanceButton.Value;
            if value == 1
                write(app.SerialDevice,'T',"uint8");
                app.ImpedanceButton.Text='EEG';
                app.Ifrun = true;            
                EEGSig(app);                
            else
                write(app.SerialDevice,'T',"uint8");
                app.ImpedanceButton.Text='Impedance';
                app.Ifrun = true;    
                EEGSig(app);
            end            
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            %% 幅值刻度设置
            % 从头开始绘图
            app.emg_cnt_state=0;%%%记录switch函数的状态
            app.emg_cnt_count=0;%%%每次计数到emg_cnt_max为止
            app.emg_idx=1;
            app.EMG_frame=zeros(9,app.EMG_CHANNEL);%%%数据解析后的数据帧
            app.result_emg=zeros(2502,app.EMG_CHANNEL) - 1;%%%绘图更新缓冲区，减1为了初始时不显示y=0的横线
            app.result_emg_idx=1;

            value = app.AmplitudeDropDown.Value;
            switch value
                case '10 uV'
                    app.Offset = 20;
                    app.UIAxes.YLim=[0 160];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150];
                case '50 uV'
                    app.Offset = 100;
                    app.UIAxes.YLim=[0 800];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*5;
                case '100 uV'
                    app.Offset = 200;
                    app.UIAxes.YLim=[0 1600];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*10;
                case '200 uV'
                    app.Offset = 400;
                    app.UIAxes.YLim=[0 3200];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*20;
                case '400 uV'
                    app.Offset = 800;
                    app.UIAxes.YLim=[0 6400];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*40;
                case '1 mV'
                    app.Offset = 2000;
                    app.UIAxes.YLim=[0 16000];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*100;
                case '3 mV'
                    app.Offset = 6000;
                    app.UIAxes.YLim=[0 48000];
                    app.UIAxes.YTick = [10 20 30 40 50 60 70 80 90 100 110 120 130 140 150]*300;
            end
        end

        % Value changed function: HighPassDropDown
        function HighPassDropDownValueChanged(app, event)
            value = app.HighPassDropDown.Value;
            switch value
                case 'None'
                    app.en_highpass = 0;
                case '0.1 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(1)/(app.fs/2),'high');
                case '1 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(2)/(app.fs/2),'high');
                case '5 Hz'
                    app.en_highpass = 1;
                    [app.b_high,app.a_high] = butter(app.filt_n,app.fc_high(3)/(app.fs/2),'high');
            end
        end

        % Value changed function: LowPassDropDown
        function LowPassDropDownValueChanged(app, event)
            value = app.LowPassDropDown.Value;
            switch value
                case 'None'
                    app.en_lowpass = 0;
                case '30 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(1)/(app.fs/2),'low');
                case '40 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(2)/(app.fs/2),'low');
                case '70 Hz'
                    app.en_lowpass = 1;
                    [app.b_low,app.a_low] = butter(app.filt_n,app.fc_low(3)/(app.fs/2),'low');
            end
        end

        % Value changed function: NotchCheckBox
        function NotchCheckBoxValueChanged(app, event)
            value = app.NotchCheckBox.Value;
            if value == 1
                app.en_notch=1;
            else
                app.en_notch=0;
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            app.UIAxes.Visible = 'off';
            app.Ifrun = false;
            for i=1:8
                set(app.line_EMG{i},'Visible','off');
            end              
            set(app.XL,'Visible','off');  
            A='T'
            app.CH1KLabel.Text = 'niaho'+ A +'shuai'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2502];
            app.UIAxes.YLim = [0 1600];
            app.UIAxes.YTick = [100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500];
            app.UIAxes.YTickLabel = {'CH8'; ''; 'CH7'; ''; 'CH6'; ''; 'CH5'; ''; 'CH4'; ''; 'CH3'; ''; 'CH2'; ''; 'CH1'};
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ImpedanceButton = uibutton(app.UIFigure, 'state');
            app.ImpedanceButton.ValueChangedFcn = createCallbackFcn(app, @ImpedanceButtonValueChanged, true);
            app.ImpedanceButton.Text = 'Impedance';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'10 uV', '50 uV', '100 uV', '200 uV', '400 uV', '1 mV', '3 mV'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '100 uV';
            app.LowPassDropDownLabel = uilabel(app.UIFigure);
            app.LowPassDropDownLabel.Text = 'LowPass';
            app.LowPassDropDown = uidropdown(app.UIFigure);
            app.LowPassDropDown.Items = {'None', '30 Hz', '40 Hz', '70 Hz'};
            app.LowPassDropDown.ValueChangedFcn = createCallbackFcn(app, @LowPassDropDownValueChanged, true);
            app.LowPassDropDown.Value = '30 Hz';
            app.HighPassDropDownLabel = uilabel(app.UIFigure);
            app.HighPassDropDownLabel.Text = 'HighPass';
            app.HighPassDropDown = uidropdown(app.UIFigure);
            app.HighPassDropDown.Items = {'None', '0.1 Hz', '1 Hz', '5 Hz'};
            app.HighPassDropDown.ValueChangedFcn = createCallbackFcn(app, @HighPassDropDownValueChanged, true);
            app.HighPassDropDown.Value = '5 Hz';
            app.NotchCheckBox = uicheckbox(app.UIFigure);
            app.NotchCheckBox.ValueChangedFcn = createCallbackFcn(app, @NotchCheckBoxValueChanged, true);
            app.NotchCheckBox.Text = 'Notch';
            app.NotchCheckBox.Value = true;
            app.RefrenceDropDownLabel = uilabel(app.UIFigure);
            app.RefrenceDropDownLabel.Text = 'Refrence';
            app.RefrenceDropDown = uidropdown(app.UIFigure);
            app.RefrenceDropDown.Items = {'None', 'Average', 'CH1', 'CH2', 'CH3', 'CH4', 'CH5', 'CH6', 'CH7', 'CH8'};
            app.RefrenceDropDown.Value = 'None';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.CH1KLabel = uilabel(app.UIFigure);
            app.CH1KLabel.Text = 'CH1：KΩ';
            app.CH1Lamp = uilamp(app.UIFigure);
            app.CH2KLabel = uilabel(app.UIFigure);
            app.CH2KLabel.Text = 'CH2：KΩ';
            app.CH2Lamp = uilamp(app.UIFigure);
            app.CH3KLabel = uilabel(app.UIFigure);
            app.CH3KLabel.Text = 'CH3：KΩ';
            app.CH3Lamp = uilamp(app.UIFigure);
            app.CH1KLamp_4Label = uilabel(app.UIFigure);
            app.CH1KLamp_4Label.Text = 'CH1：KΩ';
            app.CH4Lamp = uilamp(app.UIFigure);
            app.CH5KLabel = uilabel(app.UIFigure);
            app.CH5KLabel.Text = 'CH5：KΩ';
            app.CH5Lamp = uilamp(app.UIFigure);
            app.CH1KLamp_6Label = uilabel(app.UIFigure);
            app.CH1KLamp_6Label.Text = 'CH1：KΩ';
            app.CH6Lamp = uilamp(app.UIFigure);
            app.CH1KLamp_7Label = uilabel(app.UIFigure);
            app.CH1KLamp_7Label.Text = 'CH1：KΩ';
            app.CH7Lamp = uilamp(app.UIFigure);
            app.CH1KLamp_8Label = uilabel(app.UIFigure);
            app.CH1KLamp_8Label.Text = 'CH1：KΩ';
            app.CH8Lamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SigViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end